classdef Validation_Tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LoadDataEditFieldLabel    matlab.ui.control.Label
        LoadDataEditField         matlab.ui.control.EditField
        LoadButton                matlab.ui.control.Button
        LaneIDDropDownLabel       matlab.ui.control.Label
        LaneIDDropDown            matlab.ui.control.DropDown
        StopHandlingButton        matlab.ui.control.Button
        FigNumEditFieldLabel      matlab.ui.control.Label
        FigNumEditField           matlab.ui.control.NumericEditField
        VehIDDropDownLabel        matlab.ui.control.Label
        VehIDDropDown             matlab.ui.control.DropDown
        IdentifyStopButton        matlab.ui.control.Button
        LoadRawEditFieldLabel     matlab.ui.control.Label
        LoadRawEditField          matlab.ui.control.EditField
        LoadRawButton             matlab.ui.control.Button
        ShowRawButton             matlab.ui.control.Button
        VehIDEditFieldLabel       matlab.ui.control.Label
        VehIDEditField            matlab.ui.control.NumericEditField
        RefreashButton            matlab.ui.control.Button
        CompareLaneButton         matlab.ui.control.Button
        ByLaneButton              matlab.ui.control.Button
        PosErrorButton            matlab.ui.control.Button
        RawUnitDropDownLabel      matlab.ui.control.Label
        RawUnitDropDown           matlab.ui.control.DropDown
        RawLineTypeDropDownLabel  matlab.ui.control.Label
        RawLineTypeDropDown       matlab.ui.control.DropDown
        ByCamButton               matlab.ui.control.Button
        RootDirEditFieldLabel     matlab.ui.control.Label
        RootDirEditField          matlab.ui.control.EditField
    end

    
    properties (Access = private)
        data % vehicle trajectory in NGSIM format
        dataVeh
        raw % Description
        rawScaleFactor = 1 % Description
        rawLineType = 'mx' % Description
        data_dir % Description
    end
    
    methods (Access = private)
        
        function showSelected(app, ind, figId)
            dataTemp = app.data(ind, :);
            indVehBreak = find(diff(dataTemp(:,1))~=0);
            dataTemp(indVehBreak+1, :) = nan;
            
            figure(figId);clf;hold on
%             plot(app.data(ind,2), app.data(ind,6))
            plot(dataTemp(:,2), dataTemp(:,6), 'LineWidth',2)
            title(num2str(sum(ind)))
            xlabel('frame ID')
            ylabel('dist. (ft)')
        end
        
        function showVehId(app, ind)
            dataTemp = app.data(ind, :);
            
            indVehBreak = find(diff(dataTemp(:,1))~=0);
            textX = dataTemp(indVehBreak+1, 2)-1;
            textY = dataTemp(indVehBreak+1, 6);
            vId = dataTemp(indVehBreak+1, 1);
            
            text(textX, textY, num2str(vId))            
        end
        
        function showVa(app, ind, figId)
            figure(figId);clf;hold on
            subplot(2,1,1)
            plot(app.data(ind,2), app.data(ind,12), 'LineWidth', 2)
            xlabel('frame ID')
            ylabel('speed (ft/s)')
            grid
            subplot(2,1,2)
            plot(app.data(ind,2), app.data(ind,13), 'LineWidth', 2)
            grid
            xlabel('frame ID')
            ylabel('accel (ft/s^2)')
        end
        
        function [indNs, indStop] = identifyNsStop(app, dataVeh)
            indSame = [abs(diff(dataVeh(:,6))) < 0.01; false];
            indNeg = [diff(dataVeh(:,6)) < 0; false];
            indNs = dataVeh(:, 12) <= 4;
            indS = dataVeh(:, 12) <= 0.3;
            
            indStop = indSame | indNeg | indS;
        end
        
        function [indStart, indEnd] = findBoundary(app, ind)
            indBreak = xor(ind(1:end-1), ind(2:end));
            indBreakRs = find(indBreak);
            
            if ind(1)
                indBreakRs = [1; indBreakRs];
            end
            
            if ind(end)
                indBreakRs = [indBreakRs; length(ind)];
            end
            
            indStart = indBreakRs(1:2:end)+1;
            indEnd = indBreakRs(2:2:end);
        end
        
        function [isStopped, stopInfo] = getStopRegion(app, indStopStart, indStopEnd, indNsStart, indNsEnd)
            vid = app.dataVeh(1,1);
            
            if ~isempty(indStopStart)
                isStopped = true;
                
                for i = 1:size(indNsStart)
                    isStartWithin = (indStopStart >= indNsStart(i)) & (indStopStart <= indNsEnd(i));
                    isEndWithin = (indStopEnd >= indNsStart(i)) & (indStopEnd <= indNsEnd(i));
                    
                    isWithin = isStartWithin & isEndWithin;
                    
                    if ~sum(isWithin)
                        indNsStart(i) = nan;
                        indNsEnd(i) = nan;
                    end
                end
                
                indNsStart = indNsStart(~isnan(indNsStart));
                indNsEnd = indNsEnd(~isnan(indNsEnd));
                               
                stopInfo = [vid*ones(size(indNsStart)) , indNsStart, indStopStart, indStopEnd, indNsEnd];
                
            else
                isStopped = false;
                stopInfo = [];
            end
        end
        
        function data = splineNearStop(app, stopInfo, data)
            for i = 1:size(stopInfo, 1)
%                 indNsStart = find(stopInfo(i,1)==data(:,2));
%                 indStop = find(stopInfo(i,2)==data(:,2));
%                 indStart = find(stopInfo(i,3)==data(:,2));
%                 indNsEnd = find(stopInfo(i,4)==data(:,2));

                indNsStart = stopInfo(:,2);
                indStop = stopInfo(:,3);
                indStart = stopInfo(:,4);
                indNsEnd = stopInfo(:,5);
                
                % set stop part
                data(indStop:indStart, [12, 13]) = 0;
                data(indStop:indStart, 6) = median(data(indStop:indStart, 6));
                
                % set part before stop
                data(indNsStart:indStop, 6) = spline(data([1:indNsStart-1 indStop:end], 2), data([1:indNsStart-1 indStop:end], 6), ...
                    data(indNsStart:indStop, 2));
                data(indNsStart:indStop, 12) = spline(data([1:indNsStart-1 indStop:end], 2), data([1:indNsStart-1 indStop:end], 12), ...
                    data(indNsStart:indStop, 2));
                data(indNsStart:indStop, 13) = spline(data([1:indNsStart-1 indStop:end], 2), data([1:indNsStart-1 indStop:end], 13), ...
                    data(indNsStart:indStop, 2));
                
                % set part after stop
                data(indStart+1:indNsEnd, 6) = spline(data([indStop:indStart indNsEnd+1:end], 2), data([indStop:indStart indNsEnd+1:end], 6), ...
                    data(indStart+1:indNsEnd, 2));
                data(indStart+1:indNsEnd, 12) = spline(data([indStop:indStart indNsEnd+1:end], 2), data([indStop:indStart indNsEnd+1:end], 12), ...
                    data(indStart+1:indNsEnd, 2));
                data(indStart+1:indNsEnd, 13) = spline(data([indStop:indStart indNsEnd+1:end], 2), data([indStop:indStart indNsEnd+1:end], 13), ...
                    data(indStart+1:indNsEnd, 2));
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
%             addpath(genpath(['data' filesep]))
            app.data_dir = app.RootDirEditField.Value;
           

            load([app.data_dir app.LoadDataEditField.Value '.mat'])
            app.data = sortrows(data, [1,2,14]);
            
            % updata lane ID tab
            app.LaneIDDropDown.Items = string([0; unique(app.data(:,14))]);
           
        end

        % Value changed function: LaneIDDropDown
        function LaneIDDropDownValueChanged(app, event)
            value = app.LaneIDDropDown.Value;
            
            ind = app.data(:,14) == str2num(value);
            figId = app.FigNumEditField.Value;
            showSelected(app, ind, figId)
            showVehId(app, ind)
            title(['Lane ' value])
            
            app.VehIDDropDown.Items = string(unique(app.data(ind,1)));
        end

        % Value changed function: VehIDDropDown
        function VehIDDropDownValueChanged(app, event)
            value = app.VehIDDropDown.Value;
            
            ind = app.data(:,1) == str2num(value) & app.data(:,14) == str2num(app.LaneIDDropDown.Value);
            showSelected(app, ind, 100)
            title(['Lane ' app.LaneIDDropDown.Value ' Veh ' value])
            
            showVa(app, ind, 101)
            title(['Lane ' app.LaneIDDropDown.Value ' Veh ' value])
            
            app.dataVeh = app.data(ind, :);
        end

        % Button pushed function: IdentifyStopButton
        function IdentifyStopButtonPushed(app, event)
            [indNs, indStop] = identifyNsStop(app, app.dataVeh);
            [indNsStart, indNsEnd] = findBoundary(app, indNs);
            [indStopStart, indStopEnd] = findBoundary(app, indStop);
            
            [isStopped, stopInfo] = getStopRegion(app, indStopStart, indStopEnd, indNsStart, indNsEnd);
                        
            if isStopped
                figure(100);hold on
    %             plot(app.dataVeh(indNsStart, 2), app.dataVeh(indNsStart, 6), '>r', 'MarkerSize', 6, 'MarkerFaceColor', 'r')
    %             plot(app.dataVeh(indNsEnd, 2), app.dataVeh(indNsEnd, 6), '<g', 'MarkerSize', 6, 'MarkerFaceColor', 'g')
    %             plot(app.dataVeh(indStopStart, 2), app.dataVeh(indStopStart, 6), 'xr', 'MarkerSize', 8)
    %             plot(app.dataVeh(indStopEnd, 2), app.dataVeh(indStopEnd, 6), 'xg', 'MarkerSize', 8)
                
                plot(app.dataVeh(stopInfo(:,2), 2), app.dataVeh(stopInfo(:,2), 6), '>r', 'MarkerSize', 6, 'MarkerFaceColor', 'r')
                plot(app.dataVeh(stopInfo(:,5), 2), app.dataVeh(stopInfo(:,5), 6), '<g', 'MarkerSize', 6, 'MarkerFaceColor', 'g')
                plot(app.dataVeh(stopInfo(:,3), 2), app.dataVeh(stopInfo(:,3), 6), 'xr', 'MarkerSize', 8)
                plot(app.dataVeh(stopInfo(:,4), 2), app.dataVeh(stopInfo(:,4), 6), 'xg', 'MarkerSize', 8)
                hold off
                
                
                data = splineNearStop(app, stopInfo, app.dataVeh);
                figure(110);clf
                subplot(311);hold on
                plot(data(:,2), data(:,6), 'LineWidth',2)
                plot(data(stopInfo(:,2), 2), data(stopInfo(:,2), 6), '>r', 'MarkerSize', 6, 'MarkerFaceColor', 'r')
                plot(data(stopInfo(:,5), 2), data(stopInfo(:,5), 6), '<g', 'MarkerSize', 6, 'MarkerFaceColor', 'g')
                plot(data(stopInfo(:,3), 2), data(stopInfo(:,3), 6), 'xr', 'MarkerSize', 8)
                plot(data(stopInfo(:,4), 2), data(stopInfo(:,4), 6), 'xg', 'MarkerSize', 8)
                subplot(312);hold on
                plot(data(:,2), data(:,12), 'LineWidth',2)
                plot(data(stopInfo(:,2), 2), data(stopInfo(:,2), 12), '>r', 'MarkerSize', 6, 'MarkerFaceColor', 'r')
                plot(data(stopInfo(:,5), 2), data(stopInfo(:,5), 12), '<g', 'MarkerSize', 6, 'MarkerFaceColor', 'g')
                plot(data(stopInfo(:,3), 2), data(stopInfo(:,3), 12), 'xr', 'MarkerSize', 8)
                plot(data(stopInfo(:,4), 2), data(stopInfo(:,4), 12), 'xg', 'MarkerSize', 8)
                subplot(313);hold on
                plot(data(:,2), data(:,13), 'LineWidth',2)
                plot(data(stopInfo(:,2), 2), data(stopInfo(:,2), 13), '>r', 'MarkerSize', 6, 'MarkerFaceColor', 'r')
                plot(data(stopInfo(:,5), 2), data(stopInfo(:,5), 13), '<g', 'MarkerSize', 6, 'MarkerFaceColor', 'g')
                plot(data(stopInfo(:,3), 2), data(stopInfo(:,3), 13), 'xr', 'MarkerSize', 8)
                plot(data(stopInfo(:,4), 2), data(stopInfo(:,4), 13), 'xg', 'MarkerSize', 8)
            end
            
        end

        % Button pushed function: StopHandlingButton
        function StopHandlingButtonPushed(app, event)
            
        end

        % Button pushed function: LoadRawButton
        function LoadRawButtonPushed(app, event)
            load([app.data_dir app.LoadRawEditField.Value '.mat'])
            app.raw = data;
        end

        % Button pushed function: ShowRawButton
        function ShowRawButtonPushed(app, event)
            lnId = str2num(app.LaneIDDropDown.Value);
            vid = str2num(app.VehIDDropDown.Value);
            
            ind = app.raw(:,1) == vid & app.raw(:,14) == lnId;
            
            figure(100)
            hold on
            plot(app.raw(ind,2), app.raw(ind,6)*app.rawScaleFactor, '.')
            hold off
        end

        % Callback function: RefreashButton, VehIDEditField
        function VehIDEditFieldValueChanged(app, event)
            vid = app.VehIDEditField.Value;
            ind = app.data(:,1) == vid;
            
            figure(200)
            showSelected(app, ind, 200)
            hold on
            indRaw = app.raw(:,1) == vid;
            plot(app.raw(indRaw,2), app.raw(indRaw,6)*app.rawScaleFactor, 'xm')
            hold off
            title(['Veh ' num2str(vid)])
            
            figure(210);clf
            ax(1)=subplot(311);
            plot(app.data(ind,2), app.data(ind,6))
            xlabel('frame ID')
            ylabel('position (ft)')
            ax(2)=subplot(312);
            plot(app.data(ind,2), app.data(ind,12))
            xlabel('frame ID')
            ylabel('speed (ft/s)')
            ax(3)=subplot(313);
            plot(app.data(ind,2), app.data(ind,13))
            xlabel('frame ID')
            ylabel('accel (ft/s^2)')
            
            linkaxes(ax, 'x')
        end

        % Button pushed function: CompareLaneButton
        function CompareLaneButtonPushed(app, event)
            figure(300);clf;hold on
            
            value = app.LaneIDDropDown.Value;
            ind = app.data(:,14) == str2num(value);
            showSelected(app, ind, 300)
            showVehId(app, ind)
            title(['Lane ' value])
            
            ind = app.raw(:, 14) == str2num(value);
            dataTemp = app.raw(ind, :);
            indVehBreak = find(diff(dataTemp(:,1))~=0);
            dataTemp(indVehBreak+1, :) = nan;
            
            hold on
            plot(dataTemp(:, 2), dataTemp(:, 6)*app.rawScaleFactor, app.rawLineType, 'LineWidth',2)
            hold off
            
        end

        % Button pushed function: ByLaneButton
        function ByLaneButtonPushed(app, event)
            vid = app.VehIDEditField.Value;
            ind = app.data(:,1) == vid;
            
            figure(201);clf;hold on
            dataVeh = app.data(ind, :);
            lnArr = unique(dataVeh(:,14));
            
            for i = 1:length(lnArr)
                indSel = dataVeh(:,14) == lnArr(i);
                plot(dataVeh(indSel, 2), dataVeh(indSel, 6), '.', 'DisplayName', ['lane ' num2str(lnArr(i))])
            end
            legend('Location','northwest')
            xlabel('frame ID')
            ylabel('pos (ft)')
        end

        % Button pushed function: PosErrorButton
        function PosErrorButtonPushed(app, event)
            % show position error between output and raw data
            % by cam
            vid = app.VehIDEditField.Value;
            ind = app.data(:,1) == vid;
            indRaw = app.raw(:,1) == vid;
            
            
            dataVeh1 = app.data(ind, :);
            dataVehRaw = app.raw(indRaw, :);
            
            dataVehRaw(:,6) = dataVehRaw(:,6) * app.rawScaleFactor;
            
            camArr = unique(dataVehRaw(:,19));
            
            figure(300);clf;hold on
            for i = 1:length(camArr)
                indSel = dataVehRaw(:,19) == camArr(i);
                
                fidSel = dataVehRaw(indSel, 2);
                posSel = dataVehRaw(indSel, 6);
                
                posError = nan(size(fidSel));
                for j = 1:length(fidSel)
                    fidInd = dataVeh1(:,2) == fidSel(j);
                    if sum(fidInd)>0
                        posError(j) = dataVeh1(fidInd, 6) - posSel(j);
                    end
                end
                plot(fidSel, posError, 'LineWidth',1.5, 'DisplayName',['cam ' num2str(i)])
            end
            
            legend('Location',"best")
            grid
            xlabel('frame ID')
            ylabel('\Delta x (ft)')
            title(['Position offset from raw data by cam, Veh ' num2str(vid)])

        end

        % Value changed function: RawUnitDropDown
        function RawUnitDropDownValueChanged(app, event)
            app.rawScaleFactor = str2num(app.RawUnitDropDown.Value);
        end

        % Value changed function: RawLineTypeDropDown
        function RawLineTypeDropDownValueChanged(app, event)
            value = app.RawLineTypeDropDown.Value;
            app.rawLineType = value;
        end

        % Button pushed function: ByCamButton
        function ByCamButtonPushed(app, event)
            vid = app.VehIDEditField.Value;
            ind = app.raw(:,1) == vid;
            
            figure(201);clf;hold on
            dataVeh = app.raw(ind, :);
            lnArr = unique(dataVeh(:,19));
            
            for i = 1:length(lnArr)
                indSel = dataVeh(:,19) == lnArr(i);
                plot(dataVeh(indSel, 2), dataVeh(indSel, 6), '.', 'DisplayName', ['cam ' num2str(lnArr(i))])
            end
            legend('Location','northwest')
            xlabel('frame ID')
            ylabel('pos (ft)')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadDataEditFieldLabel = uilabel(app.UIFigure);
            app.LoadDataEditFieldLabel.Text = 'Load Data';
            app.LoadDataEditField = uieditfield(app.UIFigure, 'text');
            app.LoadDataEditField.Value = 'output/cam1234_lane3_combined_v7';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LaneIDDropDownLabel = uilabel(app.UIFigure);
            app.LaneIDDropDownLabel.Text = 'Lane ID';
            app.LaneIDDropDown = uidropdown(app.UIFigure);
            app.LaneIDDropDown.ValueChangedFcn = createCallbackFcn(app, @LaneIDDropDownValueChanged, true);
            app.StopHandlingButton = uibutton(app.UIFigure, 'push');
            app.StopHandlingButton.ButtonPushedFcn = createCallbackFcn(app, @StopHandlingButtonPushed, true);
            app.StopHandlingButton.Text = 'Stop Handling';
            app.FigNumEditFieldLabel = uilabel(app.UIFigure);
            app.FigNumEditFieldLabel.Text = 'Fig Num';
            app.FigNumEditField = uieditfield(app.UIFigure, 'numeric');
            app.FigNumEditField.ValueDisplayFormat = '%.0f';
            app.FigNumEditField.Value = 1;
            app.VehIDDropDownLabel = uilabel(app.UIFigure);
            app.VehIDDropDownLabel.Text = 'Veh ID';
            app.VehIDDropDown = uidropdown(app.UIFigure);
            app.VehIDDropDown.ValueChangedFcn = createCallbackFcn(app, @VehIDDropDownValueChanged, true);
            app.IdentifyStopButton = uibutton(app.UIFigure, 'push');
            app.IdentifyStopButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyStopButtonPushed, true);
            app.IdentifyStopButton.Text = 'Identify Stop';
            app.LoadRawEditFieldLabel = uilabel(app.UIFigure);
            app.LoadRawEditFieldLabel.Text = 'Load Raw';
            app.LoadRawEditField = uieditfield(app.UIFigure, 'text');
            app.LoadRawEditField.Value = 'cam1234_lane3_combined_v7';
            app.LoadRawButton = uibutton(app.UIFigure, 'push');
            app.LoadRawButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRawButtonPushed, true);
            app.LoadRawButton.Text = 'Load';
            app.ShowRawButton = uibutton(app.UIFigure, 'push');
            app.ShowRawButton.ButtonPushedFcn = createCallbackFcn(app, @ShowRawButtonPushed, true);
            app.ShowRawButton.Text = 'Show Raw';
            app.VehIDEditFieldLabel = uilabel(app.UIFigure);
            app.VehIDEditFieldLabel.Text = 'Veh ID';
            app.VehIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.VehIDEditField.ValueDisplayFormat = '%.0f';
            app.VehIDEditField.ValueChangedFcn = createCallbackFcn(app, @VehIDEditFieldValueChanged, true);
            app.RefreashButton = uibutton(app.UIFigure, 'push');
            app.RefreashButton.ButtonPushedFcn = createCallbackFcn(app, @VehIDEditFieldValueChanged, true);
            app.RefreashButton.Text = 'Refreash';
            app.CompareLaneButton = uibutton(app.UIFigure, 'push');
            app.CompareLaneButton.ButtonPushedFcn = createCallbackFcn(app, @CompareLaneButtonPushed, true);
            app.CompareLaneButton.Text = 'Compare Lane';
            app.ByLaneButton = uibutton(app.UIFigure, 'push');
            app.ByLaneButton.ButtonPushedFcn = createCallbackFcn(app, @ByLaneButtonPushed, true);
            app.ByLaneButton.Text = 'By Lane';
            app.PosErrorButton = uibutton(app.UIFigure, 'push');
            app.PosErrorButton.ButtonPushedFcn = createCallbackFcn(app, @PosErrorButtonPushed, true);
            app.PosErrorButton.Text = 'Pos Error';
            app.RawUnitDropDownLabel = uilabel(app.UIFigure);
            app.RawUnitDropDownLabel.Text = 'Raw Unit';
            app.RawUnitDropDown = uidropdown(app.UIFigure);
            app.RawUnitDropDown.Items = {'feet', 'pixel', 'meter'};
            app.RawUnitDropDown.ItemsData = {'1', '0.248', '3.2808', ''};
            app.RawUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @RawUnitDropDownValueChanged, true);
            app.RawUnitDropDown.Value = '1';
            app.RawLineTypeDropDownLabel = uilabel(app.UIFigure);
            app.RawLineTypeDropDownLabel.Text = 'Raw Line Type';
            app.RawLineTypeDropDown = uidropdown(app.UIFigure);
            app.RawLineTypeDropDown.Items = {'mx', 'm-', 'm--'};
            app.RawLineTypeDropDown.ItemsData = {'mx', 'm-', 'm--'};
            app.RawLineTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @RawLineTypeDropDownValueChanged, true);
            app.RawLineTypeDropDown.Value = 'mx';
            app.ByCamButton = uibutton(app.UIFigure, 'push');
            app.ByCamButton.ButtonPushedFcn = createCallbackFcn(app, @ByCamButtonPushed, true);
            app.ByCamButton.Text = 'By Cam';
            app.RootDirEditFieldLabel = uilabel(app.UIFigure);
            app.RootDirEditFieldLabel.Text = 'Root Dir';
            app.RootDirEditField = uieditfield(app.UIFigure, 'text');
            app.RootDirEditField.Value = '../data/rawdata_combined_v7/';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Validation_Tool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end